classdef PUMA560 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        TextArea                      matlab.ui.control.TextArea
        TabGroup                      matlab.ui.container.TabGroup
        ForwardKinematicsTab          matlab.ui.container.Tab
        THETA6EditField               matlab.ui.control.NumericEditField
        THETA6EditFieldLabel          matlab.ui.control.Label
        THETA5EditField               matlab.ui.control.NumericEditField
        THETA5EditFieldLabel          matlab.ui.control.Label
        THETA4EditField               matlab.ui.control.NumericEditField
        THETA4EditFieldLabel          matlab.ui.control.Label
        THETA3EditField               matlab.ui.control.NumericEditField
        THETA3EditFieldLabel          matlab.ui.control.Label
        THETA2EditField               matlab.ui.control.NumericEditField
        THETA2EditFieldLabel          matlab.ui.control.Label
        THETA1EditField               matlab.ui.control.NumericEditField
        THETA1EditFieldLabel          matlab.ui.control.Label
        FORWARDButton                 matlab.ui.control.Button
        InverseKinematicsTab          matlab.ui.container.Tab
        PzEditField                   matlab.ui.control.NumericEditField
        PzEditFieldLabel              matlab.ui.control.Label
        PyEditField                   matlab.ui.control.NumericEditField
        PyEditFieldLabel              matlab.ui.control.Label
        PxEditField                   matlab.ui.control.NumericEditField
        PxEditFieldLabel              matlab.ui.control.Label
        azEditField                   matlab.ui.control.NumericEditField
        azEditFieldLabel              matlab.ui.control.Label
        ayEditField                   matlab.ui.control.NumericEditField
        ayEditFieldLabel              matlab.ui.control.Label
        axEditField                   matlab.ui.control.NumericEditField
        axEditFieldLabel              matlab.ui.control.Label
        ozEditField                   matlab.ui.control.NumericEditField
        ozEditFieldLabel              matlab.ui.control.Label
        oyEditField                   matlab.ui.control.NumericEditField
        oyEditFieldLabel              matlab.ui.control.Label
        oxEditField                   matlab.ui.control.NumericEditField
        oxEditFieldLabel              matlab.ui.control.Label
        nzEditField                   matlab.ui.control.NumericEditField
        nzEditFieldLabel              matlab.ui.control.Label
        nyEditField                   matlab.ui.control.NumericEditField
        nyEditFieldLabel              matlab.ui.control.Label
        nxEditField                   matlab.ui.control.NumericEditField
        nxEditFieldLabel              matlab.ui.control.Label
        INVERSEButton                 matlab.ui.control.Button
        AnimationTab                  matlab.ui.container.Tab
        ANIMATEButton                 matlab.ui.control.Button
        numberofframesEditField       matlab.ui.control.NumericEditField
        numberofframesEditFieldLabel  matlab.ui.control.Label
        Theta5Panel                   matlab.ui.container.Panel
        endingEditField_5             matlab.ui.control.NumericEditField
        endingEditField_5Label        matlab.ui.control.Label
        startingEditField_5           matlab.ui.control.NumericEditField
        startingEditField_5Label      matlab.ui.control.Label
        Theta2Panel                   matlab.ui.container.Panel
        endingEditField_2             matlab.ui.control.NumericEditField
        endingEditField_2Label        matlab.ui.control.Label
        startingEditField_2           matlab.ui.control.NumericEditField
        startingEditField_2Label      matlab.ui.control.Label
        Theta3Panel                   matlab.ui.container.Panel
        endingEditField_3             matlab.ui.control.NumericEditField
        endingEditField_3Label        matlab.ui.control.Label
        startingEditField_3           matlab.ui.control.NumericEditField
        startingEditField_3Label      matlab.ui.control.Label
        Theta4Panel                   matlab.ui.container.Panel
        endingEditField_4             matlab.ui.control.NumericEditField
        endingEditField_4Label        matlab.ui.control.Label
        startingEditField_4           matlab.ui.control.NumericEditField
        startingEditField_4Label      matlab.ui.control.Label
        Theta6Panel                   matlab.ui.container.Panel
        endingEditField_6             matlab.ui.control.NumericEditField
        endingEditField_6Label        matlab.ui.control.Label
        startingEditField_6           matlab.ui.control.NumericEditField
        startingEditField_6Label      matlab.ui.control.Label
        Theta1Panel                   matlab.ui.container.Panel
        endingEditField               matlab.ui.control.NumericEditField
        endingEditFieldLabel          matlab.ui.control.Label
        startingEditField             matlab.ui.control.NumericEditField
        startingEditFieldLabel        matlab.ui.control.Label
        JacobianTab                   matlab.ui.container.Tab
        COMPUTEJACOBIANButton         matlab.ui.control.Button
        JOINT5Panel                   matlab.ui.container.Panel
        JV5EditField                  matlab.ui.control.NumericEditField
        JV5EditFieldLabel             matlab.ui.control.Label
        theta5EditField               matlab.ui.control.NumericEditField
        theta5EditFieldLabel          matlab.ui.control.Label
        JOINT2Panel                   matlab.ui.container.Panel
        JV2EditField                  matlab.ui.control.NumericEditField
        JV2EditFieldLabel             matlab.ui.control.Label
        theta2EditField               matlab.ui.control.NumericEditField
        theta2EditFieldLabel          matlab.ui.control.Label
        JOINT3Panel                   matlab.ui.container.Panel
        JV3EditField                  matlab.ui.control.NumericEditField
        JV3EditFieldLabel             matlab.ui.control.Label
        theta3EditField               matlab.ui.control.NumericEditField
        theta3EditFieldLabel          matlab.ui.control.Label
        JOINT4Panel                   matlab.ui.container.Panel
        JV4EditField                  matlab.ui.control.NumericEditField
        JV4EditFieldLabel             matlab.ui.control.Label
        theta4EditField               matlab.ui.control.NumericEditField
        theta4EditFieldLabel          matlab.ui.control.Label
        JOINT6Panel                   matlab.ui.container.Panel
        JV6EditField                  matlab.ui.control.NumericEditField
        JV6EditFieldLabel             matlab.ui.control.Label
        theta6EditField               matlab.ui.control.NumericEditField
        theta6EditFieldLabel          matlab.ui.control.Label
        JOINT1Panel                   matlab.ui.container.Panel
        JV1EditField                  matlab.ui.control.NumericEditField
        JV1EditFieldLabel             matlab.ui.control.Label
        theta1EditField               matlab.ui.control.NumericEditField
        theta1EditFieldLabel          matlab.ui.control.Label
        DHparametersTab               matlab.ui.container.Tab
        DHParametersButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        th1;th2;th3;th4;th5;th6; % Description
        
        nx;ny;nz;ox;oy;oz;Px;Py;Pz;ax;ay;az;
        
        theta1s,theta2s,theta3s,theta4s,theta5s,theta6s,theta1e,theta2e,...
            theta3e,theta4e,theta5e,theta6e,num;
        
        THETA1,THETA2,THETA3,THETA4,THETA5,THETA6,JV1,JV2,JV3,JV4,JV5,JV6;
    end
    
    methods (Access = private)
        
        function setPromptFcn(app,jTextArea,eventData,newPrompt)
           % Prevent overlapping reentry due to prompt replacement
          persistent inProgress
          if isempty(inProgress)
            inProgress = 1;  %#ok unused
          else
            return;
          end
         
          try
            % *** Prompt modification code goes here ***
            cwText = char(jTextArea.getText);
            app.TextArea.Value = cwText;
            % force prompt-change callback to fizzle-out...
            pause(0.02);
          catch
            % Never mind - ignore errors...
            %disp("error")
          end
         
          % Enable new callbacks now that the prompt has been modified
          inProgress = [];
         
        end  
    end
    
    methods (Access = public)
        
        function results = output(app)
                    try
                    jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                    jCmdWin = jDesktop.getClient('Command Window');
                    jTextArea = jCmdWin.getComponent(0).getViewport.getView;
                    set(jTextArea,'CaretUpdateCallback',@app.setPromptFcn)
                    catch
                    warndlg('fatal error');
                    end
  
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FORWARDButton
        function FORWARDButtonPushed(app, event)
            app.th1 = app.THETA1EditField.Value;
            app.th2 = app.THETA2EditField.Value;
            app.th3 = app.THETA3EditField.Value;
            app.th4 = app.THETA4EditField.Value;
            app.th5 = app.THETA5EditField.Value;
            app.th6 = app.THETA6EditField.Value;
            forwardPUMA(app.th1,app.th2,app.th3,app.th4,app.th5,app.th6);
            
            output(app);


        end

        % Button pushed function: INVERSEButton
        function INVERSEButtonPushed(app, event)
            app.nx = app.nxEditField.Value;
            app.ny = app.nyEditField.Value;
            app.nz = app.nzEditField.Value;
            app.ox = app.oxEditField.Value;
            app.oy = app.oyEditField.Value;
            app.oz = app.ozEditField.Value;
            app.ax = app.axEditField.Value;
            app.ay = app.ayEditField.Value;
            app.az = app.azEditField.Value;
            app.Px = app.PxEditField.Value;
            app.Py = app.PyEditField.Value;
            app.Pz = app.PzEditField.Value;
            
            iPUMA(app.nx,app.ny,app.nz,app.ox,app.oy,app.oz,app.ax,app.ay,app.az,app.Px,app.Py,app.Pz);
            
            output(app);
           
        end

        % Button pushed function: ANIMATEButton
        function ANIMATEButtonPushed(app, event)
            app.theta1s = app.startingEditField.Value;
            app.theta1e = app.endingEditField.Value;
            app.theta2s = app.startingEditField_2.Value;
            app.theta2e = app.endingEditField_2.Value;
            app.theta3s = app.startingEditField_3.Value;
            app.theta3e = app.endingEditField_3.Value;
            app.theta4s = app.startingEditField_4.Value;
            app.theta4e  = app.endingEditField_4.Value;
            app.theta5s = app.startingEditField_5.Value;
            app.theta5e = app.endingEditField_5.Value;
            app.theta6s  = app.startingEditField_6.Value;
            app.theta6e = app.endingEditField_6.Value;
            app.num = app.numberofframesEditField.Value;
            animation(app.theta1s,app.theta2s,app.theta3s,app.theta4s,...
                app.theta5s,app.theta6s,app.theta1e,app.theta2e,...
            app.theta3e,app.theta4e,app.theta5e,app.theta6e,app.num);
            
            
        end

        % Button pushed function: COMPUTEJACOBIANButton
        function COMPUTEJACOBIANButtonPushed(app, event)
            app.THETA1 = app.theta1EditField.Value;
            app.THETA2 = app.theta2EditField.Value;
            app.THETA3 = app.theta3EditField.Value;
            app.THETA4 = app.theta4EditField.Value;
            app.THETA5 = app.theta5EditField.Value;
            app.THETA6 = app.theta6EditField.Value;
            app.JV1 = app.JV1EditField.Value;
            app.JV2 = app.JV2EditField.Value;
            app.JV3 = app.JV3EditField.Value;
            app.JV4 = app.JV4EditField.Value;
            app.JV5 = app.JV5EditField.Value;
            app.JV6 = app.JV6EditField.Value;
            puma560_jacobian(app.THETA1,app.THETA2,app.THETA3,app.THETA4,...
                app.THETA5,app.THETA6,app.JV1,app.JV2,app.JV3,app.JV4,app.JV5,app.JV6);
            
           output(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if (selectedTab == app.ForwardKinematicsTab | selectedTab == app.InverseKinematicsTab ...
                    |selectedTab == app.JacobianTab)
                app.Panel.Visible = 'on';
                app.TextArea.Visible = 'on';
                app.UITable.Visible = 'off';
            elseif selectedTab == app.DHparametersTab
                app.Panel.Visible = 'on';
                app.TextArea.Visible = 'off';
                app.UITable.Visible = 'on';             


            else 
                app.Panel.Visible = 'off';
                
            
            end
        end

        % Button pushed function: DHParametersButton
        function DHParametersButtonPushed(app, event)
                   
                   J = ["theta1" "theta2" "theta3" "theta4" "theta5" "theta6"]; %joint angles
                    A = [-90 0 90 -90 90 0] ; %twist angle
                    r = [0 431.80 -20.32 0 0 0]; %offset as to xn
                    d = [671.83 139.70 0 431.80 0 56.50]; %offset as to z(n-1)
                    Jin=J';
                    Ain=A';
                    rin=r';
                    din=d';
                   dT = [Jin Ain rin din];
                  app.UITable.Data = dT;  
                   app.UITable.ColumnName = {'θ','α','r','d'};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ForwardKinematicsTab = uitab(app.TabGroup);
            app.ForwardKinematicsTab.Title = 'Forward Kinematics';
            app.FORWARDButton = uibutton(app.ForwardKinematicsTab, 'push');
            app.FORWARDButton.ButtonPushedFcn = createCallbackFcn(app, @FORWARDButtonPushed, true);
            app.FORWARDButton.Text = 'FORWARD';
            app.THETA1EditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.THETA1EditFieldLabel.Text = 'THETA1';
            app.THETA1EditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.THETA2EditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.THETA2EditFieldLabel.Text = 'THETA2';
            app.THETA2EditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.THETA3EditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.THETA3EditFieldLabel.Text = 'THETA3';
            app.THETA3EditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.THETA4EditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.THETA4EditFieldLabel.Text = 'THETA4';
            app.THETA4EditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.THETA5EditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.THETA5EditFieldLabel.Text = 'THETA5';
            app.THETA5EditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.THETA6EditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.THETA6EditFieldLabel.Text = 'THETA6';
            app.THETA6EditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.InverseKinematicsTab = uitab(app.TabGroup);
            app.InverseKinematicsTab.Title = 'Inverse Kinematics';
            app.INVERSEButton = uibutton(app.InverseKinematicsTab, 'push');
            app.INVERSEButton.ButtonPushedFcn = createCallbackFcn(app, @INVERSEButtonPushed, true);
            app.INVERSEButton.Text = 'INVERSE';
            app.nxEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.nxEditFieldLabel.Text = 'nx';
            app.nxEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.nyEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.nyEditFieldLabel.Text = 'ny';
            app.nyEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.nzEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.nzEditFieldLabel.Text = 'nz';
            app.nzEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.oxEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.oxEditFieldLabel.Text = 'ox';
            app.oxEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.oyEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.oyEditFieldLabel.Text = 'oy';
            app.oyEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.ozEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.ozEditFieldLabel.Text = 'oz';
            app.ozEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.axEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.axEditFieldLabel.Text = 'ax';
            app.axEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.ayEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.ayEditFieldLabel.Text = 'ay';
            app.ayEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.azEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.azEditFieldLabel.Text = 'az';
            app.azEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.PxEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.PxEditFieldLabel.Text = 'Px';
            app.PxEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.PyEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.PyEditFieldLabel.Text = 'Py';
            app.PyEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.PzEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.PzEditFieldLabel.Text = 'Pz';
            app.PzEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.AnimationTab = uitab(app.TabGroup);
            app.AnimationTab.Title = 'Animation';
            app.Theta1Panel = uipanel(app.AnimationTab);
            app.Theta1Panel.Title = 'Theta 1';
            app.startingEditFieldLabel = uilabel(app.Theta1Panel);
            app.startingEditFieldLabel.Text = 'starting';
            app.startingEditField = uieditfield(app.Theta1Panel, 'numeric');
            app.endingEditFieldLabel = uilabel(app.Theta1Panel);
            app.endingEditFieldLabel.Text = 'ending';
            app.endingEditField = uieditfield(app.Theta1Panel, 'numeric');
            app.Theta6Panel = uipanel(app.AnimationTab);
            app.Theta6Panel.Title = 'Theta 6';
            app.startingEditField_6Label = uilabel(app.Theta6Panel);
            app.startingEditField_6Label.Text = 'starting';
            app.startingEditField_6 = uieditfield(app.Theta6Panel, 'numeric');
            app.endingEditField_6Label = uilabel(app.Theta6Panel);
            app.endingEditField_6Label.Text = 'ending';
            app.endingEditField_6 = uieditfield(app.Theta6Panel, 'numeric');
            app.Theta4Panel = uipanel(app.AnimationTab);
            app.Theta4Panel.Title = 'Theta 4';
            app.startingEditField_4Label = uilabel(app.Theta4Panel);
            app.startingEditField_4Label.Text = 'starting';
            app.startingEditField_4 = uieditfield(app.Theta4Panel, 'numeric');
            app.endingEditField_4Label = uilabel(app.Theta4Panel);
            app.endingEditField_4Label.Text = 'ending';
            app.endingEditField_4 = uieditfield(app.Theta4Panel, 'numeric');
            app.Theta3Panel = uipanel(app.AnimationTab);
            app.Theta3Panel.Title = 'Theta 3';
            app.startingEditField_3Label = uilabel(app.Theta3Panel);
            app.startingEditField_3Label.Text = 'starting';
            app.startingEditField_3 = uieditfield(app.Theta3Panel, 'numeric');
            app.endingEditField_3Label = uilabel(app.Theta3Panel);
            app.endingEditField_3Label.Text = 'ending';
            app.endingEditField_3 = uieditfield(app.Theta3Panel, 'numeric');
            app.Theta2Panel = uipanel(app.AnimationTab);
            app.Theta2Panel.Title = 'Theta 2';
            app.startingEditField_2Label = uilabel(app.Theta2Panel);
            app.startingEditField_2Label.Text = 'starting';
            app.startingEditField_2 = uieditfield(app.Theta2Panel, 'numeric');
            app.endingEditField_2Label = uilabel(app.Theta2Panel);
            app.endingEditField_2Label.Text = 'ending';
            app.endingEditField_2 = uieditfield(app.Theta2Panel, 'numeric');
            app.Theta5Panel = uipanel(app.AnimationTab);
            app.Theta5Panel.Title = 'Theta 5';
            app.startingEditField_5Label = uilabel(app.Theta5Panel);
            app.startingEditField_5Label.Text = 'starting';
            app.startingEditField_5 = uieditfield(app.Theta5Panel, 'numeric');
            app.endingEditField_5Label = uilabel(app.Theta5Panel);
            app.endingEditField_5Label.Text = 'ending';
            app.endingEditField_5 = uieditfield(app.Theta5Panel, 'numeric');
            app.numberofframesEditFieldLabel = uilabel(app.AnimationTab);
            app.numberofframesEditFieldLabel.Text = 'number of frames';
            app.numberofframesEditField = uieditfield(app.AnimationTab, 'numeric');
            app.ANIMATEButton = uibutton(app.AnimationTab, 'push');
            app.ANIMATEButton.ButtonPushedFcn = createCallbackFcn(app, @ANIMATEButtonPushed, true);
            app.ANIMATEButton.Text = 'ANIMATE';
            app.JacobianTab = uitab(app.TabGroup);
            app.JacobianTab.Title = 'Jacobian';
            app.JOINT1Panel = uipanel(app.JacobianTab);
            app.JOINT1Panel.Title = 'JOINT 1';
            app.theta1EditFieldLabel = uilabel(app.JOINT1Panel);
            app.theta1EditFieldLabel.Text = 'theta1';
            app.theta1EditField = uieditfield(app.JOINT1Panel, 'numeric');
            app.JV1EditFieldLabel = uilabel(app.JOINT1Panel);
            app.JV1EditFieldLabel.Text = 'JV1';
            app.JV1EditField = uieditfield(app.JOINT1Panel, 'numeric');
            app.JOINT6Panel = uipanel(app.JacobianTab);
            app.JOINT6Panel.Title = 'JOINT 6';
            app.theta6EditFieldLabel = uilabel(app.JOINT6Panel);
            app.theta6EditFieldLabel.Text = 'theta6';
            app.theta6EditField = uieditfield(app.JOINT6Panel, 'numeric');
            app.JV6EditFieldLabel = uilabel(app.JOINT6Panel);
            app.JV6EditFieldLabel.Text = 'JV6';
            app.JV6EditField = uieditfield(app.JOINT6Panel, 'numeric');
            app.JOINT4Panel = uipanel(app.JacobianTab);
            app.JOINT4Panel.Title = 'JOINT 4';
            app.theta4EditFieldLabel = uilabel(app.JOINT4Panel);
            app.theta4EditFieldLabel.Text = 'theta4';
            app.theta4EditField = uieditfield(app.JOINT4Panel, 'numeric');
            app.JV4EditFieldLabel = uilabel(app.JOINT4Panel);
            app.JV4EditFieldLabel.Text = 'JV4';
            app.JV4EditField = uieditfield(app.JOINT4Panel, 'numeric');
            app.JOINT3Panel = uipanel(app.JacobianTab);
            app.JOINT3Panel.Title = 'JOINT 3';
            app.theta3EditFieldLabel = uilabel(app.JOINT3Panel);
            app.theta3EditFieldLabel.Text = 'theta3';
            app.theta3EditField = uieditfield(app.JOINT3Panel, 'numeric');
            app.JV3EditFieldLabel = uilabel(app.JOINT3Panel);
            app.JV3EditFieldLabel.Text = 'JV3';
            app.JV3EditField = uieditfield(app.JOINT3Panel, 'numeric');
            app.JOINT2Panel = uipanel(app.JacobianTab);
            app.JOINT2Panel.Title = 'JOINT 2';
            app.theta2EditFieldLabel = uilabel(app.JOINT2Panel);
            app.theta2EditFieldLabel.Text = 'theta2';
            app.theta2EditField = uieditfield(app.JOINT2Panel, 'numeric');
            app.JV2EditFieldLabel = uilabel(app.JOINT2Panel);
            app.JV2EditFieldLabel.Text = 'JV2';
            app.JV2EditField = uieditfield(app.JOINT2Panel, 'numeric');
            app.JOINT5Panel = uipanel(app.JacobianTab);
            app.JOINT5Panel.Title = 'JOINT 5';
            app.theta5EditFieldLabel = uilabel(app.JOINT5Panel);
            app.theta5EditFieldLabel.Text = 'theta5';
            app.theta5EditField = uieditfield(app.JOINT5Panel, 'numeric');
            app.JV5EditFieldLabel = uilabel(app.JOINT5Panel);
            app.JV5EditFieldLabel.Text = 'JV5';
            app.JV5EditField = uieditfield(app.JOINT5Panel, 'numeric');
            app.COMPUTEJACOBIANButton = uibutton(app.JacobianTab, 'push');
            app.COMPUTEJACOBIANButton.ButtonPushedFcn = createCallbackFcn(app, @COMPUTEJACOBIANButtonPushed, true);
            app.COMPUTEJACOBIANButton.Text = 'COMPUTE JACOBIAN';
            app.DHparametersTab = uitab(app.TabGroup);
            app.DHparametersTab.Title = 'DH parameters';
            app.DHParametersButton = uibutton(app.DHparametersTab, 'push');
            app.DHParametersButton.ButtonPushedFcn = createCallbackFcn(app, @DHParametersButtonPushed, true);
            app.DHParametersButton.Text = 'DH Parameters';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TextArea = uitextarea(app.Panel);
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PUMA560

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end